classdef megami < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainWindow              matlab.ui.Figure               % Megami - The im...
        PanelOperation          matlab.ui.container.Panel      % Select Operation
        TabGroupOperation       matlab.ui.container.TabGroup   % Off, Point Oper...
        TabOff                  matlab.ui.container.Tab        % Off
        ButtonDisplayRaw        matlab.ui.control.Button       % Display
        TabPO                   matlab.ui.container.Tab        % Point Operation
        TabGroupPO              matlab.ui.container.TabGroup   % Brightness, Con...
        TabBrightness           matlab.ui.container.Tab        % Brightness
        LabelKnob               matlab.ui.control.Label        % Brightness
        KnobBrightness          matlab.ui.control.Knob         % [-127 127]
        ButtonBrightnessDisplay matlab.ui.control.Button       % Display
        TabContrast             matlab.ui.container.Tab        % Contrast
        LabelKnob2              matlab.ui.control.Label        % Contrast
        KnobContrast            matlab.ui.control.Knob         % [-100 100]
        ButtonContrastDisplay   matlab.ui.control.Button       % Display
        TabGamma                matlab.ui.container.Tab        % Gamma
        LabelKnob3              matlab.ui.control.Label        % Gamma
        KnobGamma               matlab.ui.control.Knob         % [-100 100]
        ButtonGammaDisplay      matlab.ui.control.Button       % Display
        TabInvert               matlab.ui.container.Tab        % Invert
        ButtonInvert            matlab.ui.control.Button       % Display Inverted
        TabFilter               matlab.ui.container.Tab        % Filter
        TabGroupFilter          matlab.ui.container.TabGroup   % Mean, Median, A...
        TabMean                 matlab.ui.container.Tab        % Mean
        LabelDiscreteKnob       matlab.ui.control.Label        % Weight Mask
        DiscreteKnobMean        matlab.ui.control.DiscreteKnob % 1, 2, 3
        Label                   matlab.ui.control.Label        % 1 1 1
        Label2                  matlab.ui.control.Label        % 1 1 1
        Label3                  matlab.ui.control.Label        % 1 2 1
        ButtonApplyMean         matlab.ui.control.Button       % Apply
        TabMedian               matlab.ui.container.Tab        % Median
        LabelSpinner            matlab.ui.control.Label        % Block
        SpinnerMedian           matlab.ui.control.Spinner      % [3 9]
        ButtonApplyMedian       matlab.ui.control.Button       % Apply
        TabATM                  matlab.ui.container.Tab        % Alpha-trimmed Mean
        LabelSpinner2           matlab.ui.control.Label        % Block
        SpinnerATMBlock         matlab.ui.control.Spinner      % [3 9]
        LabelSpinner3           matlab.ui.control.Label        % Trim
        SpinnerATMTrim          matlab.ui.control.Spinner      % [0 4]
        ButtonApplyATM          matlab.ui.control.Button       % Apply
        TabSRF                  matlab.ui.container.Tab        % Scale/Rotate/Flip
        TabGroupSRF             matlab.ui.container.TabGroup   % Rotate, Flip, S...
        TabRotate               matlab.ui.container.Tab        % Rotate
        Rotate0                 matlab.ui.control.Button       % Rotate 0
        Rotate90                matlab.ui.control.Button       % Rotate 90
        Rotate270               matlab.ui.control.Button       % Rotate 270
        Rotate180               matlab.ui.control.Button       % Rotate 180
        TabFlip                 matlab.ui.container.Tab        % Flip
        FlipNo                  matlab.ui.control.Button       % No Flip
        FlipV                   matlab.ui.control.Button       % Vertical
        FlipH                   matlab.ui.control.Button       % Horizontal
        FlipVH                  matlab.ui.control.Button       % Both
        TabSNN                  matlab.ui.container.Tab        % Scale - Nearest...
        TabSB                   matlab.ui.container.Tab        % Scale - Bilinear
        TabED                   matlab.ui.container.Tab        % Edge Detect
        TabHist                 matlab.ui.container.Tab        % Histogram
        PanelFile               matlab.ui.container.Panel      % Select image
        LabelEditField          matlab.ui.control.Label        % Path
        EditFieldPath           matlab.ui.control.EditField   
        ButtonBrowse            matlab.ui.control.Button       % Browse
        LabelSpinner4           matlab.ui.control.Label        % Width
        SpinnerSBW              matlab.ui.control.Spinner      % [1 Inf]
        LabelSpinner5           matlab.ui.control.Label        % Height
        SpinnerSBH              matlab.ui.control.Spinner      % [1 Inf]
        ButtonSB                matlab.ui.control.Button       % Process
        LabelToggleSwitch       matlab.ui.control.Label        % Scale Select
        ToggleSwitchScale       matlab.ui.control.ToggleSwitch % Reduce, Enlarge
        LabelSpinner6           matlab.ui.control.Label        % Height Ratio
        SpinnerSNNHR            matlab.ui.control.Spinner      % [1 100]
        LabelSpinner7           matlab.ui.control.Label        % Width Ratio
        SpinnerSNNWR            matlab.ui.control.Spinner      % [1 100]
        ButtonSNN               matlab.ui.control.Button       % Process
        ButtonED                matlab.ui.control.Button       % Edge Detect
        ButtonHist              matlab.ui.control.Button       % Show
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % ButtonBrowse button pushed function
        function ButtonBrowseButtonPushed(app)
            [name, path, ~] = uigetfile(...
                {
                '*.jpg;*.jpe;*.jpeg;*.tif;*.tiff;*.png;*.bmp;*.dib', 'Image files';
                '*.jpg;*.jpe;*.jpeg', 'JPEG files';
                '*.tif;*.tiff', 'TIFF files';
                '*.png', 'PNG files';
                '*.bmp;*.dib', 'Bitmap images'
                },...
                'Open an image'...
                );
            
            if isequal(name, 0)
                
            else
                app.EditFieldPath.Value = fullfile(path, name);
            end
        end

        % ButtonDisplayRaw button pushed function
        function ButtonDisplayRawButtonPushed(app)
            I = imread(app.EditFieldPath.Value);
            imshow(I);
        end

        % ButtonBrightnessDisplay button pushed function
        function ButtonBrightnessDisplayButtonPushed(app)
            I = imread(app.EditFieldPath.Value);
            I = I + app.KnobBrightness.Value;
            imshow(I);
        end

        % ButtonContrastDisplay button pushed function
        function ButtonContrastDisplayButtonPushed(app)
            I = imread(app.EditFieldPath.Value);
            I = I * 10 ^ (app.KnobContrast.Value/100);
            imshow(I);
        end

        % ButtonGammaDisplay button pushed function
        function ButtonGammaDisplayButtonPushed(app)
            I = im2double(imread(app.EditFieldPath.Value));
            if app.KnobGamma.Value < 0
                c = 1/abs(app.KnobGamma.Value);
            else
                c = app.KnobGamma.Value;
            end
            I2 = I.^(1/c);
            imshow(I2);
        end

        % ButtonInvert button pushed function
        function ButtonInvertButtonPushed(app)
            I = imread(app.EditFieldPath.Value);
            I2 = 255 - I;
            imshow(I2);
        end

        % ButtonApplyMean button pushed function
        function ButtonApplyMeanButtonPushed(app)
            I = im2double(imread(app.EditFieldPath.Value));
            
            if ndims(I) > 2
                I = rgb2gray(I);
            end
            
            if app.DiscreteKnobMean.Value == 1
                b = [1 1 1; 1 1 1; 1 1 1];
            elseif app.DiscreteKnobMean.Value == 2
                b = [1 1 1; 1 2 1; 1 1 1];
            elseif app.DiscreteKnobMean.Value == 3
                b = [1 2 1; 2 4 2; 1 2 1];
            else
                b = [1 1 1; 1 1 1; 1 1 1];
            end
            
            bs = 3;
            overflow = ceil(bs/2);
            
            paddedI = zeros(size(I) + bs-1);
            paddedI(overflow:end-overflow+1, overflow:end-overflow+1) = I;
            I2 = zeros(size(I));
            
            for i = 1:size(I, 1)
                for j = 1:size(I, 2)
                    window = (paddedI(i:i+bs-1, j:j+bs-1).*b/sum(sum(b))*bs*bs);
                    I2(i, j) = mean(reshape(window', bs*bs, 1));
                end
            end
            
            imshow(I2);
        end

        % ButtonApplyMedian button pushed function
        function ButtonApplyMedianButtonPushed(app)
            I = im2double(imread(app.EditFieldPath.Value));
            
            if ndims(I) > 2
                I = rgb2gray(I);
            end
            
            bs = app.SpinnerMedian.Value;
            overflow = ceil(bs/2);
            
            paddedI = zeros(size(I) + bs-1);
            paddedI(overflow:end-overflow+1, overflow:end-overflow+1) = I;
            I2 = zeros(size(I));
            
            for i = 1:size(I, 1)
                for j = 1:size(I, 2)
                    I2(i, j) = median(reshape(paddedI(i:i+bs-1, j:j+bs-1)', bs*bs, 1));
                end
            end
            
            imshow(I2);
        end

        % SpinnerATMBlock value changed function
        function SpinnerATMBlockValueChanged(app)
            value = app.SpinnerATMBlock.Value;
            
            app.SpinnerATMTrim.Limits = [0 (value*value - 1)/2];
        end

        % ButtonApplyATM button pushed function
        function ButtonApplyATMButtonPushed(app)
            I = im2double(imread(app.EditFieldPath.Value));
            
            if ndims(I) > 2
                I = rgb2gray(I);
            end
            
            bs = app.SpinnerATMBlock.Value;
            p = app.SpinnerATMTrim.Value;
            overflow = ceil(bs/2);
            
            paddedI = zeros(size(I) + bs-1);
            paddedI(overflow:end-overflow+1, overflow:end-overflow+1) = I;
            I2 = zeros(size(I));
            
            for i = 1:size(I, 1)
                for j = 1:size(I, 2)
                    window = sort(reshape(paddedI(i:i+bs-1, j:j+bs-1)', bs*bs, 1));
                    I2(i, j) = mean(window(p+1:end-p));
                end
            end
            
            imshow(I2);
        end

        % Rotate0 button pushed function
        function Rotate0ButtonPushed(app)
            I = imread(app.EditFieldPath.Value);
            imshow(I);
        end

        % Rotate90 button pushed function
        function Rotate90ButtonPushed(app)
            I = imread(app.EditFieldPath.Value);
            I = rot90(I);
            imshow(I);
        end

        % Rotate180 button pushed function
        function Rotate180ButtonPushed(app)
            I = imread(app.EditFieldPath.Value);
            I = rot90(I, 2);
            imshow(I);
        end

        % Rotate270 button pushed function
        function Rotate270ButtonPushed(app)
            I = imread(app.EditFieldPath.Value);
            I = rot90(I, 3);
            imshow(I);
        end

        % FlipNo button pushed function
        function FlipNoButtonPushed(app)
            I = imread(app.EditFieldPath.Value);
            imshow(I);
        end

        % FlipV button pushed function
        function FlipVButtonPushed(app)
            I = imread(app.EditFieldPath.Value);
            I = fliplr(I);
            imshow(I);
        end

        % FlipH button pushed function
        function FlipHButtonPushed(app)
            I = imread(app.EditFieldPath.Value);
            I = fliplr(I);
            imshow(I);
        end

        % FlipVH button pushed function
        function FlipVHButtonPushed(app)
            I = imread(app.EditFieldPath.Value);
            I = rot90(I, 2);
            imshow(I);
        end

        % ButtonSB button pushed function
        function ButtonSBButtonPushed(app)
            I = imread(app.EditFieldPath.Value);
            [h, w, ~] = size(I);
            
            nh = app.SpinnerSBH.Value;
            nw = app.SpinnerSBW.Value;
            
            rh = h / nh;
            rw = w / nw;
            
            [fw, fh] = meshgrid(1:nw, 1:nh);
            fh = fh * rh;
            fw = fw * rw;
            
            floor_h = floor(fh);
            floor_w = floor(fw);
            
            floor_h(floor_h < 1) = 1;
            floor_h(floor_h > h - 1) = h - 1;
            floor_w(floor_w < 1) = 1;
            floor_h(floor_h > h - 1) = h - 1;
            
            dif_h = fh - floor_h;
            dif_w = fw - floor_w;
            
            in1_ind = sub2ind([h, w], floor_h, floor_w);
            in2_ind = sub2ind([h, w], floor_h + 1, floor_w);
            in3_ind = sub2ind([h, w], floor_h, floor_w );
            in4_ind = sub2ind([h, w], floor_h + 1, floor_w);
            
            out = zeros(nh, nw, size(I, 3));
            out = cast(out, class(I));
            
            for idx = 1 : size(I, 3)
                chan = double(I(:,:,idx));
                tmp = chan(in1_ind).*(1 - dif_h).*(1 - dif_w) + ...
                    chan(in2_ind).*(dif_h).*(1 - dif_w) + ...
                    chan(in3_ind).*(1 - dif_h).*(dif_w) + ...
                    chan(in4_ind).*(dif_h).*(dif_w);
                out(:,:,idx) = cast(tmp, class(I));
            end
            
            imshow(out)
        end

        % ButtonSNN button pushed function
        function ButtonSNNButtonPushed(app)
            I = imread(app.EditFieldPath.Value);
            n = app.SpinnerSNNHR.Value;
            m = app.SpinnerSNNWR.Value;
            
            if strcmp(app.ToggleSwitchScale.Value, 'Reduce')
                I2 = I(round(1:1/n:end),round(1:1/m:end),:);
            else
                I2 = I(1:n:end,1:m:end,:);
            end
            
            imshow(I2);
        end

        % ButtonED button pushed function
        function ButtonEDButtonPushed(app)
            I = rgb2gray(im2double(imread(app.EditFieldPath.Value)));
            I2 = zeros(size(I));
            
            for i = 1:size(I, 1) - 2
                for j = 1:size(I, 2) - 2
                    Gx=((2*I(i+2, j+1) + I(i+2, j) + I(i+2, j+2)) - (2*I(i, j+1) + I(i, j) + I(i, j+2)));
                    Gy=((2*I(i+1, j+2) + I(i, j+2) + I(i+2, j+2)) - (2*I(i+1, j) + I(i, j) + I(i+2, j)));
                    
                    I2(i,j)=sqrt(Gx.^2+Gy.^2);
                end
            end
            
            imshow(I2);
        end

        % ButtonHist button pushed function
        function ButtonHistButtonPushed(app)
            
            I = imread (app.EditFieldPath.Value);
            
            subplot(2, 2, 1), imshow(I);
            subplot(2, 2, 2), imhist(I(:,:,1)); % R
            subplot(2, 2, 3), imhist(I(:,:,2)); % G
            subplot(2, 2, 4), imhist(I(:,:,3)); % B
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainWindow = uifigure;
            app.MainWindow.Name = 'Megami - The image processing app';
            setAutoResize(app, app.MainWindow, true)
            app.PanelOperation = uipanel(app.MainWindow);
            app.PanelOperation.Title = 'Select Operation';
            app.PanelOperation.Units = 'pixels';
            app.TabGroupOperation = uitabgroup(app.PanelOperation);
            app.TabGroupOperation.Units = 'pixels';
            app.TabOff = uitab(app.TabGroupOperation);
            app.TabOff.Units = 'pixels';
            app.TabOff.Title = 'Off';
            app.ButtonDisplayRaw = uibutton(app.TabOff, 'push');
            app.ButtonDisplayRaw.ButtonPushedFcn = createCallbackFcn(app, @ButtonDisplayRawButtonPushed);
            app.ButtonDisplayRaw.Text = 'Display';
            app.TabPO = uitab(app.TabGroupOperation);
            app.TabPO.Units = 'pixels';
            app.TabPO.Title = 'Point Operation';
            app.TabGroupPO = uitabgroup(app.TabPO);
            app.TabGroupPO.Units = 'pixels';
            app.TabBrightness = uitab(app.TabGroupPO);
            app.TabBrightness.Units = 'pixels';
            app.TabBrightness.Title = 'Brightness';
            app.LabelKnob = uilabel(app.TabBrightness);
            app.LabelKnob.Text = 'Brightness';
            app.KnobBrightness = uiknob(app.TabBrightness, 'continuous');
            app.KnobBrightness.Limits = [-127 127];
            app.ButtonBrightnessDisplay = uibutton(app.TabBrightness, 'push');
            app.ButtonBrightnessDisplay.ButtonPushedFcn = createCallbackFcn(app, @ButtonBrightnessDisplayButtonPushed);
            app.ButtonBrightnessDisplay.Text = 'Display';
            app.TabContrast = uitab(app.TabGroupPO);
            app.TabContrast.Units = 'pixels';
            app.TabContrast.Title = 'Contrast';
            app.LabelKnob2 = uilabel(app.TabContrast);
            app.LabelKnob2.Text = 'Contrast';
            app.KnobContrast = uiknob(app.TabContrast, 'continuous');
            app.KnobContrast.Limits = [-100 100];
            app.ButtonContrastDisplay = uibutton(app.TabContrast, 'push');
            app.ButtonContrastDisplay.ButtonPushedFcn = createCallbackFcn(app, @ButtonContrastDisplayButtonPushed);
            app.ButtonContrastDisplay.Text = 'Display';
            app.TabGamma = uitab(app.TabGroupPO);
            app.TabGamma.Units = 'pixels';
            app.TabGamma.Title = 'Gamma';
            app.LabelKnob3 = uilabel(app.TabGamma);
            app.LabelKnob3.Text = 'Gamma';
            app.KnobGamma = uiknob(app.TabGamma, 'continuous');
            app.KnobGamma.Limits = [-100 100];
            app.ButtonGammaDisplay = uibutton(app.TabGamma, 'push');
            app.ButtonGammaDisplay.ButtonPushedFcn = createCallbackFcn(app, @ButtonGammaDisplayButtonPushed);
            app.ButtonGammaDisplay.Text = 'Display';
            app.TabInvert = uitab(app.TabGroupPO);
            app.TabInvert.Units = 'pixels';
            app.TabInvert.Title = 'Invert';
            app.ButtonInvert = uibutton(app.TabInvert, 'push');
            app.ButtonInvert.ButtonPushedFcn = createCallbackFcn(app, @ButtonInvertButtonPushed);
            app.ButtonInvert.Text = 'Display Inverted';
            app.TabFilter = uitab(app.TabGroupOperation);
            app.TabFilter.Units = 'pixels';
            app.TabFilter.Title = 'Filter';
            app.TabGroupFilter = uitabgroup(app.TabFilter);
            app.TabGroupFilter.Units = 'pixels';
            app.TabMean = uitab(app.TabGroupFilter);
            app.TabMean.Units = 'pixels';
            app.TabMean.Title = 'Mean';
            app.LabelDiscreteKnob = uilabel(app.TabMean);
            app.LabelDiscreteKnob.Text = 'Weight Mask';
            app.DiscreteKnobMean = uiknob(app.TabMean, 'discrete');
            app.DiscreteKnobMean.Items = {'1', '2', '3'};
            app.DiscreteKnobMean.Value = '1';
            app.Label = uilabel(app.TabMean);
            app.Label.Text = {'1 1 1'; '1 1 1'; '1 1 1'};
            app.Label2 = uilabel(app.TabMean);
            app.Label2.Text = {'1 1 1'; '1 2 1'; '1 1 1'};
            app.Label3 = uilabel(app.TabMean);
            app.Label3.Text = {'1 2 1'; '2 4 2'; '1 2 1'};
            app.ButtonApplyMean = uibutton(app.TabMean, 'push');
            app.ButtonApplyMean.ButtonPushedFcn = createCallbackFcn(app, @ButtonApplyMeanButtonPushed);
            app.ButtonApplyMean.Text = 'Apply';
            app.TabMedian = uitab(app.TabGroupFilter);
            app.TabMedian.Units = 'pixels';
            app.TabMedian.Title = 'Median';
            app.LabelSpinner = uilabel(app.TabMedian);
            app.LabelSpinner.Text = 'Block';
            app.SpinnerMedian = uispinner(app.TabMedian);
            app.SpinnerMedian.Step = 2;
            app.SpinnerMedian.Limits = [3 9];
            app.SpinnerMedian.RoundFractionalValues = 'on';
            app.SpinnerMedian.ValueDisplayFormat = '%.0f';
            app.SpinnerMedian.Value = 3;
            app.ButtonApplyMedian = uibutton(app.TabMedian, 'push');
            app.ButtonApplyMedian.ButtonPushedFcn = createCallbackFcn(app, @ButtonApplyMedianButtonPushed);
            app.ButtonApplyMedian.Text = 'Apply';
            app.TabATM = uitab(app.TabGroupFilter);
            app.TabATM.Units = 'pixels';
            app.TabATM.Title = 'Alpha-trimmed Mean';
            app.LabelSpinner2 = uilabel(app.TabATM);
            app.LabelSpinner2.Text = 'Block';
            app.SpinnerATMBlock = uispinner(app.TabATM);
            app.SpinnerATMBlock.Step = 2;
            app.SpinnerATMBlock.ValueChangedFcn = createCallbackFcn(app, @SpinnerATMBlockValueChanged);
            app.SpinnerATMBlock.Limits = [3 9];
            app.SpinnerATMBlock.RoundFractionalValues = 'on';
            app.SpinnerATMBlock.ValueDisplayFormat = '%.0f';
            app.SpinnerATMBlock.Editable = 'off';
            app.SpinnerATMBlock.Value = 3;
            app.LabelSpinner3 = uilabel(app.TabATM);
            app.LabelSpinner3.Text = 'Trim';
            app.SpinnerATMTrim = uispinner(app.TabATM);
            app.SpinnerATMTrim.Limits = [0 4];
            app.SpinnerATMTrim.RoundFractionalValues = 'on';
            app.SpinnerATMTrim.ValueDisplayFormat = '%.0f';
            app.ButtonApplyATM = uibutton(app.TabATM, 'push');
            app.ButtonApplyATM.ButtonPushedFcn = createCallbackFcn(app, @ButtonApplyATMButtonPushed);
            app.ButtonApplyATM.Text = 'Apply';
            app.TabSRF = uitab(app.TabGroupOperation);
            app.TabSRF.Units = 'pixels';
            app.TabSRF.Title = 'Scale/Rotate/Flip';
            app.TabGroupSRF = uitabgroup(app.TabSRF);
            app.TabGroupSRF.Units = 'pixels';
            app.TabRotate = uitab(app.TabGroupSRF);
            app.TabRotate.Units = 'pixels';
            app.TabRotate.Title = 'Rotate';
            app.Rotate0 = uibutton(app.TabRotate, 'push');
            app.Rotate0.ButtonPushedFcn = createCallbackFcn(app, @Rotate0ButtonPushed);
            app.Rotate0.Text = 'Rotate 0';
            app.Rotate90 = uibutton(app.TabRotate, 'push');
            app.Rotate90.ButtonPushedFcn = createCallbackFcn(app, @Rotate90ButtonPushed);
            app.Rotate90.Text = 'Rotate 90';
            app.Rotate270 = uibutton(app.TabRotate, 'push');
            app.Rotate270.ButtonPushedFcn = createCallbackFcn(app, @Rotate270ButtonPushed);
            app.Rotate270.Text = 'Rotate 270';
            app.Rotate180 = uibutton(app.TabRotate, 'push');
            app.Rotate180.ButtonPushedFcn = createCallbackFcn(app, @Rotate180ButtonPushed);
            app.Rotate180.Text = 'Rotate 180';
            app.TabFlip = uitab(app.TabGroupSRF);
            app.TabFlip.Units = 'pixels';
            app.TabFlip.Title = 'Flip';
            app.FlipNo = uibutton(app.TabFlip, 'push');
            app.FlipNo.ButtonPushedFcn = createCallbackFcn(app, @FlipNoButtonPushed);
            app.FlipNo.Text = 'No Flip';
            app.FlipV = uibutton(app.TabFlip, 'push');
            app.FlipV.ButtonPushedFcn = createCallbackFcn(app, @FlipVButtonPushed);
            app.FlipV.Text = 'Vertical';
            app.FlipH = uibutton(app.TabFlip, 'push');
            app.FlipH.ButtonPushedFcn = createCallbackFcn(app, @FlipHButtonPushed);
            app.FlipH.Text = 'Horizontal';
            app.FlipVH = uibutton(app.TabFlip, 'push');
            app.FlipVH.ButtonPushedFcn = createCallbackFcn(app, @FlipVHButtonPushed);
            app.FlipVH.Text = 'Both';
            app.TabSNN = uitab(app.TabGroupSRF);
            app.TabSNN.Units = 'pixels';
            app.TabSNN.Title = 'Scale - Nearest Neighbor';
            app.LabelToggleSwitch = uilabel(app.TabSNN);
            app.LabelToggleSwitch.Text = 'Scale Select';
            app.ToggleSwitchScale = uiswitch(app.TabSNN, 'toggle');
            app.ToggleSwitchScale.Items = {'Reduce', 'Enlarge'};
            app.ToggleSwitchScale.Value = 'Reduce';
            app.LabelSpinner6 = uilabel(app.TabSNN);
            app.LabelSpinner6.Text = 'Height Ratio';
            app.SpinnerSNNHR = uispinner(app.TabSNN);
            app.SpinnerSNNHR.Limits = [1 100];
            app.SpinnerSNNHR.Value = 1;
            app.LabelSpinner7 = uilabel(app.TabSNN);
            app.LabelSpinner7.Text = 'Width Ratio';
            app.SpinnerSNNWR = uispinner(app.TabSNN);
            app.SpinnerSNNWR.Limits = [1 100];
            app.SpinnerSNNWR.Value = 1;
            app.ButtonSNN = uibutton(app.TabSNN, 'push');
            app.ButtonSNN.ButtonPushedFcn = createCallbackFcn(app, @ButtonSNNButtonPushed);
            app.ButtonSNN.Text = 'Process';
            app.TabSB = uitab(app.TabGroupSRF);
            app.TabSB.Units = 'pixels';
            app.TabSB.Title = 'Scale - Bilinear';
            app.LabelSpinner4 = uilabel(app.TabSB);
            app.LabelSpinner4.Text = 'Width';
            app.SpinnerSBW = uispinner(app.TabSB);
            app.SpinnerSBW.Limits = [1 Inf];
            app.SpinnerSBW.ValueDisplayFormat = '%.0f';
            app.SpinnerSBW.Value = 100;
            app.LabelSpinner5 = uilabel(app.TabSB);
            app.LabelSpinner5.Text = 'Height';
            app.SpinnerSBH = uispinner(app.TabSB);
            app.SpinnerSBH.Limits = [1 Inf];
            app.SpinnerSBH.ValueDisplayFormat = '%.0f';
            app.SpinnerSBH.Value = 100;
            app.ButtonSB = uibutton(app.TabSB, 'push');
            app.ButtonSB.ButtonPushedFcn = createCallbackFcn(app, @ButtonSBButtonPushed);
            app.ButtonSB.Text = 'Process';
            app.TabED = uitab(app.TabGroupOperation);
            app.TabED.Units = 'pixels';
            app.TabED.Title = 'Edge Detect';
            app.ButtonED = uibutton(app.TabED, 'push');
            app.ButtonED.ButtonPushedFcn = createCallbackFcn(app, @ButtonEDButtonPushed);
            app.ButtonED.Text = 'Edge Detect';
            app.TabHist = uitab(app.TabGroupOperation);
            app.TabHist.Units = 'pixels';
            app.TabHist.Title = 'Histogram';
            app.ButtonHist = uibutton(app.TabHist, 'push');
            app.ButtonHist.ButtonPushedFcn = createCallbackFcn(app, @ButtonHistButtonPushed);
            app.ButtonHist.Text = 'Show';
            app.PanelFile = uipanel(app.MainWindow);
            app.PanelFile.Title = 'Select image';
            app.PanelFile.Units = 'pixels';
            app.LabelEditField = uilabel(app.PanelFile);
            app.LabelEditField.Enable = 'off';
            app.LabelEditField.Text = 'Path';
            app.EditFieldPath = uieditfield(app.PanelFile, 'text');
            app.EditFieldPath.Enable = 'off';
            app.ButtonBrowse = uibutton(app.PanelFile, 'push');
            app.ButtonBrowse.ButtonPushedFcn = createCallbackFcn(app, @ButtonBrowseButtonPushed);
            app.ButtonBrowse.Text = 'Browse';
        end
    end

    methods (Access = public)

        % Construct app
        function app = megami()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainWindow)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainWindow)
        end
    end
end